classdef etchAsketchapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        algoSliderText              matlab.ui.control.Label
        algoSlider                  matlab.ui.control.Slider
        AlgorithmSelectionDropDown  matlab.ui.control.DropDown
        AlgorithmSelectionDropDownLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        TextLocationY_button        matlab.ui.control.EditField
        Run_button                  matlab.ui.control.Button
        EstimatedTime_text          matlab.ui.control.EditField
        AddTextToImageLabel_3       matlab.ui.control.Label
        Reset_button                matlab.ui.control.Button
        TextLocationX_button        matlab.ui.control.EditField
        AddTextToImageLabel_4       matlab.ui.control.Label
        Preview_button              matlab.ui.control.Button
        EnterText                   matlab.ui.control.EditField
        AddTextToImageLabel         matlab.ui.control.Label
        ResolutionSlider            matlab.ui.control.Slider
        BrightnessSlider            matlab.ui.control.Slider
        ContrastSlider              matlab.ui.control.Slider
        OpenImage_button            matlab.ui.control.Button
        FileName_text               matlab.ui.control.EditField
        AddTextToImageLabel_2       matlab.ui.control.Label
        ResolutionSliderLabel       matlab.ui.control.Label
        BrightnessSliderLabel       matlab.ui.control.Label
        ContrastSliderLabel         matlab.ui.control.Label
        ROBOTICETCHASKETCHLabel     matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        Knob_2                      matlab.ui.control.Knob
        Knob                        matlab.ui.control.Knob
        figure                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Current image
        inputText_position = [100, 300] % Description
        backupimg
        resolutionVal
        contrastVal
        brightnessVal
        algo = "Contours"
        algoSliderVal = 150
    end
    
    methods (Access = private)
        %%% Our Functions %%%
        function updateImage(app)
            app.Image.ImageSource = cat(3, app.img, app.img, app.img);
        end

        function run(app)
            % This is the main code that runs and controls functions.  With this code
            % The arduino stepper commands are controlled to move in x y positions
            % Stored in position matrix, mat: columns of x and y positions
                        
            global arduino_object; % Create an Arduino object
            
            arduino_object = arduino('com6', 'Uno');  %this is an internal program in matlab that creates a follower program in the Arduino that is controlled by the leader matlab program.   The first time this runs it will take a little longer as it will downlad the follower program into the arduino.
            
            %Set and configure pins for arduino
            global stepX dirX stepY dirY enPin;
            
            stepX = 'D2';
            dirX  = 'D5';
            stepY = 'D3';
            dirY  = 'D6';
            enPin = 'D8';
            
            configurePin(arduino_object,'D2','DigitalOutput')
            configurePin(arduino_object,'D5','DigitalOutput')
            configurePin(arduino_object,'D3','DigitalOutput')
            configurePin(arduino_object,'D6','DigitalOutput')
            configurePin(arduino_object,'D8','DigitalOutput')
            
            % Set initial position, lower left hand corner
            % Full range is approximately rangex = 875 rangey = 610
            global currentx currenty;
            currentx = 0;
            currenty = 0;
            
            % Establishes where backlash starts: 0 is negative, 1 is positive
            % Note initial value only influences minor position of x,y start axis
            global currentdirx currentdiry;
            currentdirx = 1;
            currentdiry = 1;
            
            % Set initial backlash; number of steps after changing direciton in order for
            % Visible movement to occur. This is a positive value
            global backlashx backlashy;
            backlashy = 8;
            backlashx = 10;
            
            global time;
            time = 0; % This value of time pauses between movements. note because of arduino and matlab communication this can only increase the speed to a certain degree. 0 is full speed.
            
            
            % Pull up list of x,y points to travel to, mat
            % A seperate function should be put here.
        
            % Preview and run
            app.preview(true);
            
            % Return home
            % moveitto(0, 0);
            
            clear all; % Clears all variables, including most importantly "a" which shuts down the stepper motors
        end

        function preview(app, run)
            app.algoSliderValueChanged();
            app.Preview_button.BackgroundColor = "Green";
            if run
                app.Run_button.BackgroundColor = "Green";
            end

            app.Image.Visible = false;
            app.figure.Visible = true;
            app.figure.Parent = app.Panel;
            app.figure.Position = [40, 20, 525, 320];
            l = animatedline('Parent', app.figure);   

            numRows = 500;
            numCols = 700;
            app.img = imresize(app.img, [numRows numCols]);

            totalRows = 600;
            totalCols = 850;
            app.figure.YLim = [0 totalRows];
            app.figure.XLim = [0 totalCols];

            app.img = im2gray(app.img);

            if or(or(app.algo == "Canny Edges", app.algo == "Contours"), app.algo == "Geometric")
                if (app.algo == "Canny Edges")
                    % Downsample image
                    scale = 0.25;
                    app.img = imresize(app.img, scale);
                    
                    threshold = [.1 app.algoSliderVal]; % [low, high]
                    edges = imcomplement(edge(app.img, 'Canny', threshold));
                    [row, col] = find(~edges);
                elseif app.algo == "Contours"
                    % Downsample image
                    scale = 0.25;
                    app.img = imresize(app.img, scale);

                    [row, col] = find(app.img < app.algoSliderVal);
                elseif app.algo == "Geometric"
                    scale = app.algoSliderVal;
                    app.img = imresize(app.img, scale);

                    array = zeros(numRows, numCols);
                    for i = 1:size(app.img, 1)
                        for j = 1:size(app.img,2)
                           pix = app.img(i, j);
                           ram = rand;
    
                           if pix <= 255 && pix >= 225
                               if ram < 0
                                   array(i,j ) = 1;
                               end
                           elseif pix <= 224 && pix >= 194
                               if ram < 0.1
                                   array(i, j) = 1;
                               end
                            elseif pix <= 193 && pix >= 163
                               if ram < 0.15
                                   array(i, j) = 1;
                               end
                             elseif pix <= 162 && pix >= 132
                               if ram < 0.2
                                   array(i, j) = 1;
                               end
                             elseif pix <= 131 && pix >= 101
                               if ram < 0.25
                                   array(i, j) = 1;
                               end
                             elseif pix <= 101 && pix >= 71
                               if ram < 0.3
                                   array(i, j) = 1;
                               end
                            elseif pix <= 70 && pix >= 40
                                ram = rand;
                               if ram < 0.35
                                   array(i, j) = 1;
                               end
                            elseif pix <= 39 && pix >=0
                               if ram < 0.4
                                   array(i, j) = 1;
                               end
                           end
                        end
                    end
                    [row, col] = find(array == 1);
                end

                mat = [col row];
                mat = mat.*(1 / scale);

                % Set start of drawing to bottom left of Etch-A-Sketch
                curX = (totalRows - numRows) / 2;
                curY = (totalCols - numCols) / 2;
                
                % Calculate points to draw
                pointsLeft = size(mat, 1);
                totalPoints = pointsLeft;
                updateIndex = 0;
                while pointsLeft > 0
                    if updateIndex == 100
                        app.updateTime(totalPoints - pointsLeft, totalPoints);
                        updateIndex = 0;
                    else
                        updateIndex = updateIndex + 1;
                    end
                    pointsLeft = pointsLeft - 1;
                    
                    % Find index of closest (x, y) pair in mat
                    index = dsearchn(mat, [curX curY]);
                
                    % Set current point to index
                    curX = mat(index, 1);
                    curY = mat(index, 2);
                   
                    % Remove index to be drawn
                    mat(index, :) = [];
                
                    % Draw on figure
                    moveX = curX;
                    moveY = numRows - curY;
                    addpoints(l, double(moveX), double(moveY))
                    if run
                        drawnow
                        app.moveitto_func(moveX, moveY)
                    else
                        drawnow limitrate
                    end                
                end
            elseif app.algo == "Column Modulation"                
                % Downsample image
                colwidth = app.algoSliderVal;
                smallcols = int32(numCols / colwidth);
                rowheight = 3;
                smallrows = int32(numRows / rowheight);
                
                newimg = imresize(app.img, [smallrows, smallcols]);
                                
                for col = 1 : smallcols
                    app.updateTime(col*smallrows, smallcols*smallrows);
                    if mod(col, 2) == 0
                        even = true;
                    else
                        even = false;
                    end
                    for row = 1 : smallrows
                        if even
                            val = double(newimg(smallrows - row + 1, col));
                            moveY = rowheight * row;  
                        else
                            val = double(newimg(row, col));
                            moveY = rowheight * (smallrows - row);  
                        end

                        width = interp1([255, 0], [0, colwidth], val);
                    
                        % Draw on figure
                        moveX = (colwidth * col) + width;

                        addpoints(l, double(moveX), double(moveY))
                        
                        if run
                            drawnow
                            app.moveitto_func(moveX, moveY)
                        else
                            drawnow limitrate
                        end
                    end
                end
            else
                disp("Error in algorithm selection")
            end
            
            % Reset button colors
            app.Preview_button.BackgroundColor = "#F4F4F4";
            app.Run_button.BackgroundColor = "#F4F4F4";
            app.EstimatedTime_text.Value = "0:0";
        end

        function updateTime(app, current, total)
            totalTime = total * 1;
            timeRemaining =  (total - current) * (totalTime / total); % interp1([0, total], [0, totalTime], current); % totalTime * (1 - current / total);
            minRem = idivide(timeRemaining, int32(60));
            if minRem > 60
                hrRem = idivide(minRem, 60);
                minRem = mod(minRem, 60);
            else
                hrRem = 0;
            end
            secRem = mod(minRem, 60);
            app.EstimatedTime_text.Value = string(hrRem) + ":" + string(minRem) + ":" + string(secRem);
        end

        function moveitto_func(app, gox, goy)
            % This function will travel to the commanded x,y position put in.
            % Bring global variables into function
            global time;
            global arduino_object;
            global currentx currenty;
            global currentdirx currentdiry;
            global backlashx backlashy;
            global stepX dirX stepY dirY enPin;
            
            % Determine screen distance to move (does not factor in backlash) in x and y directions
            disx = gox-currentx;
            disy = goy-currenty;
            
            % Mark direction of new movement
            % This is needed for backlash consideration
            if disx <= 0
                directionx = 0;
            else
                directionx = 1;
            end
            
            if disy <= 0
                directiony = 0;
            else
                directiony = 1;
            end
        
            %did direction change in the x? and was there was movement in the x?  if yes backlash needs
            %is added or subtracted in
            if directionx ~= currentdirx && disx ~= 0
                
                if directionx == 0 % Directionx change and is now negative
                     disx = disx - backlashx; % Backlash must be subtracted
                end
                
                if directionx == 1 %directionx change and is now positive
                     disx = disx + backlashx; % Backlash must be added
                end   
                
                currentdirx = directionx; % For next time function is called currentdirx is set
                
            end
            
            % Did direction change in the y? and was there was movement in the y?  if yes backlash needs to be added or subtracted in
            if directiony ~= currentdiry && disy ~= 0
                if directiony == 0 %directiony change and is now negative
                     disy = disy - backlashy; %backlash must be subtracted
                end
                
                if directiony == 1 % Directiony change and is now positive
                     disy = disy + backlashy; % Backlash must be added
                end   
               
                 currentdiry = directiony; %for next time function is called currentdiry is set
                
            end
            
            
            % Position input to step commands are positive when read into arduino
            disx = abs(disx);
            disy = abs(disy);
            
            
            if disx > disy   % Deterime which one is large movement
                ratio = disx / disy;  % Deterime ratio greater than or equal to 1
                largesize = disx;  % Major movement set
                bigdir = dirY;     % Pins determined to control major and minor movement
                smalldir = dirX;   
                bigstep = stepY;    
                smallstep = stepX;
                bigdirectionvalue = directionx;  %direction set
                smalldirectionvalue = directiony;
            else
                ratio = disy / disx; %deterime ratio greater than or equal to 1
                largesize = disy;  %major movement set
                bigdir = dirX;   %pins determined to control major and minor movement
                smalldir = dirY;
                bigstep = stepX;
                smallstep = stepY;
                bigdirectionvalue = directiony;   %direction set
                smalldirectionvalue = directionx;
            end  
                
            count = 1;  
            
            if (disx + disy) > 0   %if both are 0 then no movement occurs, This can accidently happen when applying rounding.
            
            writeDigitalPin(arduino_object, bigdir, bigdirectionvalue); % Writes direction
            writeDigitalPin(arduino_object, smalldir, smalldirectionvalue); % Writes direction
            
            
            for i = 1:largesize
                
                writeDigitalPin(arduino_object, bigstep, 1); % Each loop writes one major steps;
                   
                 if i == round(ratio * count) && ratio ~= 1/0   %if it is due, a minor step is written,  ratio~=1/0 means minor step is never written if minor step=0
                    writeDigitalPin(arduino_object, smallstep, 1);
                 end
                
                %pause(time/1000);
                writeDigitalPin(arduino_object, bigstep, 0);
                
                if i == round(ratio * count) && ratio ~= 1/0   %if it is due a minor step is written
                writeDigitalPin(arduino_object, smallstep,0); 
                count = count + 1; %sets number of counts from small movement, used to determine if minor step is due
                end
                    
                    
            end
            end
            currentx = gox;
            currenty = goy;
        
        end
  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenImage_button
        function OpenImage_buttonButtonPushed(app, event)
            imgName = uigetfile('*.jpg');
            figure(app.UIFigure);
            app.img = im2gray(imread(imgName));            
            %app.img = imresize(app.img, [500 800]);
            app.updateImage();
            app.FileName_text.Value = imgName;
            app.backupimg = app.img;
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            value = app.ContrastSlider.Value;
            if value > 0
                low_out = interp1([-5, 5], [0, 1], value - 5);
                high_out = 1 - low_out;
                app.img = imadjust(app.img, [low_out high_out], []);

            elseif value < 0
                low_out = 0;
                high_out = interp1([-5, 5], [0, 1], value);
                app.img = imadjust(app.img, [low_out high_out], []);
            end
            app.updateImage();
            app.contrastVal = value;
        end

        % Button pushed function: Preview_button
        function Preview_buttonButtonPushed(app, event)
            app.preview(false);
        end

        % Value changed function: EnterText
        function EnterTextValueChanged(app, event)
            inputText = app.EnterText.Value;
            disp(inputText)
            app.img = insertText(app.img, app.inputText_position, inputText, FontSize = 64, TextColor='black', BoxOpacity = 0);
            app.Image.ImageSource = app.img;
            %app.updateImage()
        end

        % Value changed function: TextLocationX_button
        function TextLocationX_buttonValueChanged(app, event)
            app.inputText_position(1) = str2double(app.TextLocationX_button.Value);
            app.EnterTextValueChanged();
        end

        % Value changed function: TextLocationY_button
        function TextLocationY_buttonValueChanged(app, event)
            app.inputText_position(2) = str2double(app.TextLocationY_button.Value);
            app.EnterTextValueChanged();            
        end

        % Value changed function: ResolutionSlider
        function ResolutionSliderValueChanged(app, event)
            value = app.ResolutionSlider.Value;
            scale = interp1([-5, 5], [0.25, 1], value);
            app.img = imresize(app.img, scale);
            app.updateImage();
            app.resolutionVal = value;
        end

        % Button pushed function: Reset_button
        function Reset_buttonButtonPushed(app, event)
            if ~isempty(allchild(app.figure))
                app.figure.Visible = false;
                cla(app.figure)
            end
            app.img = app.backupimg;
            app.Image.Visible = true;
            app.contrastVal = 0;
            app.ContrastSlider.Value = 0;
            app.brightnessVal = 0;
            app.BrightnessSlider.Value = 0;
            app.resolutionVal = 0;
            app.ResolutionSlider.Value = 0;
            app.updateImage();
            app.Image.Visible = true;
            app.algo = "Contours";
            app.AlgorithmSelectionDropDownValueChanged();
            
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            value = app.BrightnessSlider.Value;
            app.img = app.img + (value * 10);
            app.updateImage();
            app.brightnessVal = value;
        end

        % Value changed function: AlgorithmSelectionDropDown
        function AlgorithmSelectionDropDownValueChanged(app, event)
            app.algo = app.AlgorithmSelectionDropDown.Value;
            if app.algo == "Column Modulation"
                app.algoSliderText.Text = 'Col Width';
                app.algoSlider.Limits = [5, 15];
                app.algoSlider.Value = 10;
            elseif app.algo == "Canny Edges"
                app.algoSliderText.Text = 'Threshold';
                app.algoSlider.Limits = [.2, .9];
                app.algoSlider.Value = .6;                
            elseif app.algo == "Contours"
                app.algoSliderText.Text = 'Cutoff';
                app.algoSlider.Limits = [0, 255];
                app.algoSlider.Value = 150;      
            elseif app.algo == "Geometric"
                app.algoSliderText.Text = 'Scale';   
                app.algoSlider.Limits = [0.1, 0.99];
                app.algoSlider.Value = 0.25;      
            end
        end

        % Button pushed function: Run_button
        function Run_buttonButtonPushed(app, event)
            app.run();
        end

        % Value changed function: algoSlider
        function algoSliderValueChanged(app, event)
            app.algoSliderVal = app.algoSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.figure = uiaxes(app.UIFigure);
            app.figure.XTick = [];
            app.figure.YTick = [];
            app.Panel = uipanel(app.UIFigure);
            app.Knob = uiknob(app.Panel, 'continuous');
            app.Knob.MajorTicks = [];
            app.Knob.MinorTicks = [];
            app.Knob_2 = uiknob(app.Panel, 'continuous');
            app.Knob_2.MajorTicks = [];
            app.Knob_2.MinorTicks = [];
            app.ROBOTICETCHASKETCHLabel = uilabel(app.UIFigure);
            app.ROBOTICETCHASKETCHLabel.Text = 'ROBOTIC ETCH-A-SKETCH';
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.ResolutionSliderLabel = uilabel(app.UIFigure);
            app.ResolutionSliderLabel.Text = 'Resolution';
            app.AddTextToImageLabel_2 = uilabel(app.UIFigure);
            app.AddTextToImageLabel_2.Text = 'File Name:';
            app.FileName_text = uieditfield(app.UIFigure, 'text');
            app.FileName_text.Editable = 'off';
            app.FileName_text.Value = 'image.jpg';
            app.OpenImage_button = uibutton(app.UIFigure, 'push');
            app.OpenImage_button.ButtonPushedFcn = createCallbackFcn(app, @OpenImage_buttonButtonPushed, true);
            app.OpenImage_button.Text = 'Open Image';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [-5 5];
            app.ContrastSlider.MajorTicks = [-5 -3 -1 1 3 5];
            app.ContrastSlider.MajorTickLabels = {'- 5', '- 3', '- 1', '+ 1', '+ 3', '+ 5'};
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [-4 -2 -1 0 1 2 4];
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.Limits = [-5 5];
            app.BrightnessSlider.MajorTicks = [-5 -3 -1 1 3 5];
            app.BrightnessSlider.MajorTickLabels = {'- 5', '- 3', '- 1', '+ 1', '+ 3', '+ 5'};
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [-4 -2 -1 0 1 2 4];
            app.ResolutionSlider = uislider(app.UIFigure);
            app.ResolutionSlider.Limits = [-5 5];
            app.ResolutionSlider.MajorTicks = [-5 -3 -1 1 3 5];
            app.ResolutionSlider.MajorTickLabels = {'- 5', '- 3', '- 1', '+ 1', '+ 3', '+ 5'};
            app.ResolutionSlider.ValueChangedFcn = createCallbackFcn(app, @ResolutionSliderValueChanged, true);
            app.ResolutionSlider.MinorTicks = [-4 -2 -1 0 1 2 4];
            app.AddTextToImageLabel = uilabel(app.UIFigure);
            app.AddTextToImageLabel.Text = 'Add Text To Image: ';
            app.EnterText = uieditfield(app.UIFigure, 'text');
            app.EnterText.ValueChangedFcn = createCallbackFcn(app, @EnterTextValueChanged, true);
            app.EnterText.Value = 'Sample Text';
            app.Preview_button = uibutton(app.UIFigure, 'push');
            app.Preview_button.ButtonPushedFcn = createCallbackFcn(app, @Preview_buttonButtonPushed, true);
            app.Preview_button.Text = 'Preview Path';
            app.AddTextToImageLabel_4 = uilabel(app.UIFigure);
            app.AddTextToImageLabel_4.Text = 'Text Location (x, y):';
            app.TextLocationX_button = uieditfield(app.UIFigure, 'text');
            app.TextLocationX_button.ValueChangedFcn = createCallbackFcn(app, @TextLocationX_buttonValueChanged, true);
            app.TextLocationX_button.Value = '100';
            app.Reset_button = uibutton(app.UIFigure, 'push');
            app.Reset_button.ButtonPushedFcn = createCallbackFcn(app, @Reset_buttonButtonPushed, true);
            app.Reset_button.Text = 'Reset';
            app.AddTextToImageLabel_3 = uilabel(app.UIFigure);
            app.AddTextToImageLabel_3.Text = 'Estimated Time To Print: ';
            app.EstimatedTime_text = uieditfield(app.UIFigure, 'text');
            app.EstimatedTime_text.Editable = 'off';
            app.EstimatedTime_text.Value = 'XX:XX';
            app.Run_button = uibutton(app.UIFigure, 'push');
            app.Run_button.ButtonPushedFcn = createCallbackFcn(app, @Run_buttonButtonPushed, true);
            app.Run_button.Text = 'Run!';
            app.TextLocationY_button = uieditfield(app.UIFigure, 'text');
            app.TextLocationY_button.ValueChangedFcn = createCallbackFcn(app, @TextLocationY_buttonValueChanged, true);
            app.TextLocationY_button.Value = '300';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'EaS_img.jpg');
            app.AlgorithmSelectionDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmSelectionDropDownLabel.Text = 'Algorithm Selection';
            app.AlgorithmSelectionDropDown = uidropdown(app.UIFigure);
            app.AlgorithmSelectionDropDown.Items = {'Canny Edges', 'Contours', 'Column Modulation', 'Geometric'};
            app.AlgorithmSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmSelectionDropDownValueChanged, true);
            app.AlgorithmSelectionDropDown.Value = 'Contours';
            app.algoSlider = uislider(app.UIFigure);
            app.algoSlider.Limits = [0 255];
            app.algoSlider.MajorTicks = [];
            app.algoSlider.MajorTickLabels = {''};
            app.algoSlider.ValueChangedFcn = createCallbackFcn(app, @algoSliderValueChanged, true);
            app.algoSlider.MinorTicks = [];
            app.algoSlider.Value = 150;
            app.algoSliderText = uilabel(app.UIFigure);
            app.algoSliderText.Text = 'Cutoff';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etchAsketchapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end